classdef Main_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExperimentcontrolpanelUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RecordTab                       matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        ExitButton                      matlab.ui.control.Button
        AlwaysverifyVLCfullscreenCheckBox  matlab.ui.control.CheckBox
        SavetimestampCheckBox           matlab.ui.control.CheckBox
        Button_dir                      matlab.ui.control.Button
        ExperimentcontrolpanelLabel     matlab.ui.control.Label
        WrittenbyShaulShvimmerElectroOpticalengineeringMScstudentLabel  matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        StopButton                      matlab.ui.control.Button
        QuestionsPopupCheckBox          matlab.ui.control.CheckBox
        CheckpositionButton             matlab.ui.control.Button
        VIScameraRefresh                matlab.ui.control.Button
        Button_crop                     matlab.ui.control.Button
        CropCheckBox                    matlab.ui.control.CheckBox
        RGB2grayCheckBox                matlab.ui.control.CheckBox
        TimeLabel                       matlab.ui.control.Label
        Status2                         matlab.ui.control.Label
        SecLabel                        matlab.ui.control.Label
        Button_right                    matlab.ui.control.Button
        Button_left                     matlab.ui.control.Button
        Button_refresh_list             matlab.ui.control.Button
        StopafterCheckBox               matlab.ui.control.CheckBox
        EditField_stop                  matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SecondsButton_stop              matlab.ui.control.RadioButton
        FramesButton_stop               matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        WarmupCheckBox                  matlab.ui.control.CheckBox
        EditField_start                 matlab.ui.control.NumericEditField
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        SecondsButton_start             matlab.ui.control.RadioButton
        FramesButton_start              matlab.ui.control.RadioButton
        Label_2                         matlab.ui.control.Label
        IRcameraasButtonGroup           matlab.ui.container.ButtonGroup
        TemperatureButton               matlab.ui.control.RadioButton
        ColorButton                     matlab.ui.control.RadioButton
        SaveSwitchLabel                 matlab.ui.control.Label
        SaveSwitch                      matlab.ui.control.Switch
        VIScameraSwitchLabel            matlab.ui.control.Label
        VIScameraSwitch                 matlab.ui.control.Switch
        PlayvideosSwitchLabel           matlab.ui.control.Label
        PlayvideosSwitch                matlab.ui.control.Switch
        VideofilesfolderEditFieldLabel  matlab.ui.control.Label
        VideofilesfolderEditField       matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        FramerateDropDownLabel          matlab.ui.control.Label
        FramerateDropDown               matlab.ui.control.DropDown
        LiveviewSwitchLabel             matlab.ui.control.Label
        LiveviewSwitch                  matlab.ui.control.Switch
        InitialblackscreenEditFieldLabel  matlab.ui.control.Label
        InitialblackscreenEditField     matlab.ui.control.NumericEditField
        PausetimeEditFieldLabel         matlab.ui.control.Label
        PausetimeEditField              matlab.ui.control.NumericEditField
        LastblackscreenEditFieldLabel   matlab.ui.control.Label
        LastblackscreenEditField        matlab.ui.control.NumericEditField
        PlaytimeDropDownLabel           matlab.ui.control.Label
        PlaytimeDropDown                matlab.ui.control.DropDown
        IRcameraSwitchLabel             matlab.ui.control.Label
        IRcameraSwitch                  matlab.ui.control.Switch
        PopuppositionEditFieldLabel     matlab.ui.control.Label
        PopuppositionEditField          matlab.ui.control.EditField
        VIScameraDropDownLabel          matlab.ui.control.Label
        VIScameraDropDown               matlab.ui.control.DropDown
        VIScameraresolutionDropDownLabel  matlab.ui.control.Label
        VIScameraresolutionDropDown     matlab.ui.control.DropDown
        CroppingcoordinatesEditFieldLabel  matlab.ui.control.Label
        CroppingcoordinatesEditField    matlab.ui.control.EditField
        StatusLabel                     matlab.ui.control.Label
        Status1                         matlab.ui.control.TextArea
        FromfolderListBoxLabel          matlab.ui.control.Label
        FromfolderListBox               matlab.ui.control.ListBox
        ToplayerListBoxLabel            matlab.ui.control.Label
        ToplayerListBox                 matlab.ui.control.ListBox
        LiveStatisticsTab               matlab.ui.container.Tab
        FPSCheckBox                     matlab.ui.control.CheckBox
        UIAxes_FPS                      matlab.ui.control.UIAxes
        ROI12CheckBox                   matlab.ui.control.CheckBox
        UIAxes_ROIdiff                  matlab.ui.control.UIAxes
        IntensityatROI1CheckBox         matlab.ui.control.CheckBox
        UIAxes_ROI_1                    matlab.ui.control.UIAxes
        SelectROIButton_2               matlab.ui.control.StateButton
        IntensityatROI2CheckBox         matlab.ui.control.CheckBox
        UIAxes_ROI_2                    matlab.ui.control.UIAxes
        SelectROIButton_3               matlab.ui.control.StateButton
        AnalayzeTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Property
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %{
            load('lastrunvar','app');
            
            app.TitleEditField.Value = app.Property.title; % file name to be saved
            app.PausetimeEditField.Value = app.Property.pauseTime; % black screen time between videos
            app.LastblackscreenEditField.Value = app.Property.lastBlackScreen; % last black screen duration
            app.VideofilesfolderEditField.Value = app.Property.location;
            app.InitialblackscreenEditField.Value = app.Property.initialBlackScreen; % first black screen duration
            
            app.PlaytimeDropDown.Value = num2str(app.Property.playTime);
            app.FramerateDropDown.Value = num2str(app.Property.constantFrameRate);
            app.SaveSwitch.Value = app.Property.save_data;
            app.VIScameraSwitch.Value = app.Property.VIS_camera;
            app.Property.live_view = app.LiveviewSwitch.Value;
            app.PlayvideosSwitch.Value = app.Property.playVideofiles;
            app.AlwaysverifyVLCfullscreenCheckBox.Value = app.Property.verifyFullscreen;
            app.SavetimestampCheckBox.Value = app.Property.timeStamp4savedFile;
            app.FPSCheckBox.Value = app.Property.dispCWstatus;
            %}
            
            %app.Property.app = app;
            
            app.VIScameraDropDown.Items = webcamlist;

            USB_found = strfind(app.VIScameraDropDown.Items,'USB Capture HDMI');
            
            if find([USB_found{:}] == 1) == 1
                app.VIScameraDropDown.Value = 'USB Capture HDMI';
            end

            value = app.VIScameraDropDown.Value;
            try
                cam = webcam(value);
                res = cam.AvailableResolutions;
                app.VIScameraresolutionDropDown.Items = res;
                clear('cam');
            catch
                app.Status1.FontColor = [0.78,0.09,0.21]; % dark red
                app.Status1.Value = sprintf('%s', ['Error connecting to ' value]);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            if strcmp(app.VIScameraSwitch.Value, 'Off') && strcmp(app.IRcameraSwitch.Value, 'Off')
                
                status(app, 'No camera was selected at all, please select at least one camera.', 'r', 1, 0);
                
                app.Lamp_3.Color = 'g';
                app.Lamp_6.Color = 'g';
                pause(0.125);
                app.Lamp_3.Color = 'r';
                app.Lamp_6.Color = 'r';
                pause(0.125)
                app.Lamp_3.Color = 'g';
                app.Lamp_6.Color = 'g';
                pause(0.125);
                app.Lamp_3.Color = 'r';
                app.Lamp_6.Color = 'r';
                
            elseif strcmp(app.SaveSwitch.Value, 'Off') && strcmp(app.LiveviewSwitch.Value, 'Off')
                
                status(app, 'No live view was selected, or data to be saved, please choose one.', 'r', 1, 0);
                
                app.Lamp.Color = 'g';
                app.Lamp_4.Color = 'g';
                pause(0.125);
                app.Lamp.Color = 'r';
                app.Lamp_4.Color = 'r';
                pause(0.125);
                app.Lamp.Color = 'g';
                app.Lamp_4.Color = 'g';
                pause(0.125);
                app.Lamp.Color = 'r';
                app.Lamp_4.Color = 'r';
                
            elseif isempty(app.CroppingcoordinatesEditField.Value) && app.CropCheckBox.Value == 1
                
                status(app, 'No cropping cordinates provided, please provide.', 'r', 1, 0);
                
                app.CroppingcoordinatesEditField.BackgroundColor = 'r';
                pause(0.125);
                app.CroppingcoordinatesEditField.BackgroundColor = 'w';
                pause(0.125);
                app.CroppingcoordinatesEditField.BackgroundColor = 'r';
                pause(0.125);
                app.CroppingcoordinatesEditField.BackgroundColor = 'w';
                
            elseif strcmp(app.VIScameraDropDown.Value, 'PI IMAGER') && strcmp(app.VIScameraSwitch.Value, 'On')
                
                status(app, 'Cannot choose PI IMAGER as VIS camera, since it is the IR camera.', 'r', 1, 0);
                
                app.VIScameraDropDown.BackgroundColor = 'r';
                pause(0.125);
                app.VIScameraDropDown.BackgroundColor = 'w';
                pause(0.125);
                app.VIScameraDropDown.BackgroundColor = 'r';
                pause(0.125);
                app.VIScameraDropDown.BackgroundColor = 'w';
                
            else % case everythings OK, prepare to run recorder function
                
                app.RunButton.Enable = 0;
                app.StopButton.Enable = 1;
                app.Status1.Value = ' ';
                app.Status2.Text = ' ';
                
                app.InitialblackscreenEditField.Enable = 0;
                app.InitialblackscreenEditFieldLabel.Enable = 0;
                app.PlaytimeDropDown.Enable = 0;
                app.PlaytimeDropDownLabel.Enable = 0;
                app.PausetimeEditField.Enable = 0;
                app.PausetimeEditFieldLabel.Enable = 0;
                app.LastblackscreenEditField.Enable = 0;
                app.LastblackscreenEditFieldLabel.Enable = 0;
                app.VideofilesfolderEditField.Enable = 0;
                app.VideofilesfolderEditFieldLabel.Enable = 0;
                app.AlwaysverifyVLCfullscreenCheckBox.Enable = 0;
                app.Button_dir.Enable = 0;
                app.TitleEditField.Enable = 0;
                app.TitleEditFieldLabel.Enable = 0;
                app.SavetimestampCheckBox.Enable = 0;
                app.FramerateDropDown.Enable = 0;
                app.FramerateDropDownLabel.Enable = 0;
                app.FPSCheckBox.Enable = 0;
                app.SaveSwitch.Enable = 0;
                app.PlayvideosSwitch.Enable = 0;
                app.VIScameraSwitch.Enable = 0;
                app.IRcameraSwitch.Enable = 0;
                app.LiveviewSwitch.Enable = 0;
                app.QuestionsPopupCheckBox.Enable = 0;
                app.PopuppositionEditField.Enable = 0;
                app.PopuppositionEditFieldLabel.Enable = 0;
                app.CheckpositionButton.Enable = 0;
                app.VIScameraDropDown.Enable = 0;
                app.VIScameraLabel.Enable = 0;
                app.VIScameraresolutionDropDown.Enable = 0;
                app.VIScameraresolutionLabel.Enable = 0;
                app.VIScameraRefresh.Enable = 0;
                app.CropCheckBox.Enable = 0;
                app.Button_crop.Enable = 0;
                app.CroppingcoordinatesEditField.Enable = 0;
                app.CroppingcoordinatesEditFieldLabel.Enable = 0;
                app.RGB2grayCheckBox.Enable = 0;
                app.FromfolderListBox.Enable = 0;
                app.FromfolderListBoxLabel.Enable = 0;
                app.ToplayerListBox.Enable = 0;
                app.ToplayerListBoxLabel.Enable = 0;
                app.Button_right.Enable = 0;
                app.Button_left.Enable = 0;
                app.Button_refresh_list.Enable = 0;
                app.StopafterCheckBox.Enable = 0;
                app.EditField_stop.Enable = 0;
                app.SecondsButton_stop.Enable = 0;
                app.FramesButton_stop.Enable = 0;
                app.Label.Enable = 0;
                app.WarmupCheckBox.Enable = 0;
                app.EditField_start.Enable = 0;
                app.SecondsButton_start.Enable = 0;
                app.FramesButton_start.Enable = 0;
                app.Label_2.Enable = 0;
                app.TemperatureButton.Enable = 0;
                app.ColorButton.Enable = 0;
                
                plot(app.UIAxes_FPS, 0, '-r'); % clean FPS graph
                
                app.Property.title = app.TitleEditField.Value; % file name to be saved
                app.Property.pauseTime = app.PausetimeEditField.Value; % black screen time between videos
                app.Property.lastBlackScreen = app.LastblackscreenEditField.Value; % last black screen duration
                app.Property.location = app.VideofilesfolderEditField.Value; % video files location on drive
                app.Property.initialBlackScreen = app.InitialblackscreenEditField.Value; % first black screen duration
                app.Property.camera2connect = app.VIScameraDropDown.Value; % choose VIS camera
                app.Property.camera_resolution = app.VIScameraresolutionDropDown.Value; % choose resolution
                
                if app.TemperatureButton.Value == 1
                    app.Property.tempORcolor = 1;
                else
                    app.Property.tempORcolor = 0;
                end

                if app.CropCheckBox.Value == 1
                    app.Property.crop_cor = app.CroppingcoordinatesEditField.Value;
                else
                    app.Property.crop_cor = 0;
                end
                
                if app.WarmupCheckBox.Value == 1
                    app.Property.warm_up = 1;
                    app.Property.warm_up_period = app.EditField_start.Value;
                    if app.SecondsButton_start.Value == 1
                        app.Property.warm_up_seconds = 1;
                    else
                        app.Property.warm_up_seconds = 0;
                    end
                else
                    app.Property.warm_up = 0;
                end
                
                if app.StopafterCheckBox.Value == 1
                    app.Property.force_end = 1;
                    app.Property.force_end_period = app.EditField_stop.Value;
                    if app.SecondsButton_stop.Value == 1
                        app.Property.force_end_seconds = 1;
                    else
                        app.Property.force_end_seconds = 0;
                    end
                else
                    app.Property.force_end = 0;
                end
                
                if strcmp(app.PlaytimeDropDown.Value, 'Auto')
                    app.Property.playTime = 0; % 0 for automatic mode, else enter a value
                else
                    app.Property.playTime = str2double(app.PlaytimeDropDown.Value);
                end
                
                if strcmp(app.FramerateDropDown.Value, 'Max')
                    app.Property.constantFrameRate = 0; % 0 = max recording frame rate possible. else, enter any other positive number
                else
                    app.Property.constantFrameRate = str2double(app.FramerateDropDown.Value); % 0 = max recording frame rate possible. else, enter any other positive number
                end
                
                if strcmp(app.SaveSwitch.Value, 'On')
                    app.Property.save_data = 1; % 1 = yes, 0 = no
                    app.StopButton.Text = 'Save & Stop';
                    app.ExitButton.Text = 'Save & Exit';
                else
                    app.Property.save_data = 0; % 1 = yes, 0 = no
                    app.StopButton.Text = 'Stop';
                    app.ExitButton.Text = 'Exit';
                end
                
                if strcmp(app.VIScameraSwitch.Value, 'On')
                    app.Property.VIS_camera = 1; % 1 = yes, 0 = no
                else
                    app.Property.VIS_camera = 0; % 1 = yes, 0 = no
                end
                
                if strcmp(app.IRcameraSwitch.Value, 'On')
                    app.Property.IR_camera = 1; % 1 = yes, 0 = no
                else
                    app.Property.IR_camera = 0; % 1 = yes, 0 = no
                end
                
                if strcmp(app.LiveviewSwitch.Value, 'On')
                    app.Property.live_view = 1; % 1 = yes, 0 = no
                else
                    app.Property.live_view = 0; % 1 = yes, 0 = no
                end
                
                if strcmp(app.PlayvideosSwitch.Value, 'On')
                    app.Property.playVideofiles = 1; % 1 = yes, 0 = no
                else
                    app.Property.playVideofiles = 0; % 1 = yes, 0 = no
                end
                
                if app.AlwaysverifyVLCfullscreenCheckBox.Value == 1
                    app.Property.verifyFullscreen = 1; % 1 = yes, 0 = no
                else
                    app.Property.verifyFullscreen = 0; % 1 = yes, 0 = no
                end
                
                if app.SavetimestampCheckBox.Value == 1
                    app.Property.timeStamp4savedFile = 1; % 1 = yes, 0 = no
                else
                    app.Property.timeStamp4savedFile = 0; % 1 = yes, 0 = no
                end
                
                if app.FPSCheckBox.Value == 1
                    app.Property.dispCWstatus = 1; % 1 = yes, 0 = no
                else
                    app.Property.dispCWstatus = 0; % 1 = yes, 0 = no
                end
                
                if app.QuestionsPopupCheckBox.Value == 1
                    app.Property.popup = 1;
                    app.Property.pos = app.PopuppositionEditField.Value; % send popup position
                else
                    app.Property.popup = 0;
                end
                
                if app.RGB2grayCheckBox.Value == 1
                    app.Property.gray = 1;
                else
                    app.Property.gray = 0;
                end
                
                % save('lastrunvar','-v7.3','app'); % saves configuration status
                
                recorder(app, app.Property); % calls the recorder function

            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            global viewer_is_running;
            viewer_is_running = 0;
            
            closereq
        end

        % Value changed function: SaveSwitch
        function SaveSwitchValueChanged(app, event)
            value = app.SaveSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp.Color = 'g';
                app.TitleEditField.Enable = 1;
                app.TitleEditFieldLabel.Enable = 1;
                app.SavetimestampCheckBox.Enable = 1;
            else
                app.Lamp.Color = 'r';
                app.TitleEditField.Enable = 0;
                app.TitleEditFieldLabel.Enable = 0;
                app.SavetimestampCheckBox.Enable = 0;
            end
            
        end

        % Callback function
        function SavetimestampSwitchValueChanged(app, event)
            
        end

        % Value changed function: VIScameraSwitch
        function VIScameraSwitchValueChanged(app, event)
            value = app.VIScameraSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp_3.Color = 'g';
                app.VIScameraDropDown.Enable = 1;
                app.VIScameraRefresh.Enable = 1;
                %app.VIScameraLabel.Enable = 1;
                app.VIScameraresolutionDropDown.Enable = 1;
                %app.VIScameraresolutionLabel.Enable = 1;
                app.CropCheckBox.Enable = 1;
                app.RGB2grayCheckBox.Enable = 1;
                
                if app.CropCheckBox.Value == 1
                    app.Button_crop.Enable = 1;
                    app.CroppingcoordinatesEditField.Enable = 1;
                    app.CroppingcoordinatesEditFieldLabel.Enable = 1;
                end
                
            else
                app.Lamp_3.Color = 'r';
                app.VIScameraDropDown.Enable = 0;
                app.VIScameraRefresh.Enable = 0;
                %app.VIScameraLabel.Enable = 0;
                app.VIScameraresolutionDropDown.Enable = 0;
                %app.VIScameraresolutionLabel.Enable = 0;
                app.CropCheckBox.Enable = 0;
                app.Button_crop.Enable = 0;
                app.CroppingcoordinatesEditField.Enable = 0;
                app.CroppingcoordinatesEditFieldLabel.Enable = 0;
                app.RGB2grayCheckBox.Enable = 0;
            end
            

        end

        % Value changed function: LiveviewSwitch
        function LiveviewSwitchValueChanged(app, event)
            value = app.LiveviewSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp_4.Color = 'g';
            else
                app.Lamp_4.Color = 'r';
            end
        end

        % Value changed function: PlayvideosSwitch
        function PlayvideosSwitchValueChanged(app, event)
            value = app.PlayvideosSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp_5.Color = 'g';
                app.InitialblackscreenEditField.Enable = 1;
                app.InitialblackscreenEditFieldLabel.Enable = 1;
                app.PlaytimeDropDown.Enable = 1;
                app.PlaytimeDropDownLabel.Enable = 1;
                app.PausetimeEditField.Enable = 1;
                app.PausetimeEditFieldLabel.Enable = 1;
                app.LastblackscreenEditField.Enable = 1;
                app.LastblackscreenEditFieldLabel.Enable = 1;
                app.VideofilesfolderEditField.Enable = 1;
                app.VideofilesfolderEditFieldLabel.Enable = 1;
                app.AlwaysverifyVLCfullscreenCheckBox.Enable = 1;
                app.Button_dir.Enable = 1;
                app.QuestionsPopupCheckBox.Enable = 1;
                app.FromfolderListBox.Enable = 1;
                app.FromfolderListBoxLabel.Enable = 1;
                app.ToplayerListBox.Enable = 1;
                app.ToplayerListBoxLabel.Enable = 1;
                app.Button_right.Enable = 1;
                app.Button_left.Enable = 1;
                app.Button_refresh_list.Enable = 1;
                
                if app.QuestionsPopupCheckBox.Value == 1
                    app.PopuppositionEditField.Enable = 1;
                    app.PopuppositionEditFieldLabel.Enable = 1;
                    app.CheckpositionButton.Enable = 1;
                else
                    app.PopuppositionEditField.Enable = 'Of';
                    app.PopuppositionEditFieldLabel.Enable = 'Of';
                    app.CheckpositionButton.Enable = 'Of';
                end
                
            else
                app.Lamp_5.Color = 'r';
                app.InitialblackscreenEditField.Enable = 0;
                app.InitialblackscreenEditFieldLabel.Enable = 0;
                app.PlaytimeDropDown.Enable = 0;
                app.PlaytimeDropDownLabel.Enable = 0;
                app.PausetimeEditField.Enable = 0;
                app.PausetimeEditFieldLabel.Enable = 0;
                app.LastblackscreenEditField.Enable = 0;
                app.LastblackscreenEditFieldLabel.Enable = 0;
                app.VideofilesfolderEditField.Enable = 0;
                app.VideofilesfolderEditFieldLabel.Enable = 0;
                app.AlwaysverifyVLCfullscreenCheckBox.Enable = 0;
                app.Button_dir.Enable = 0;
                app.QuestionsPopupCheckBox.Enable = 0;
                app.PopuppositionEditField.Enable = 0;
                app.PopuppositionEditFieldLabel.Enable = 0;
                app.CheckpositionButton.Enable = 0;
                app.FromfolderListBox.Enable = 0;
                app.FromfolderListBoxLabel.Enable = 0;
                app.ToplayerListBox.Enable = 0;
                app.ToplayerListBoxLabel.Enable = 0;
                app.Button_right.Enable = 0;
                app.Button_left.Enable = 0;
                app.Button_refresh_list.Enable = 0;
                
            end
        end

        % Button pushed function: Button_dir
        function Button_dirPushed(app, event)
            try
                dname = uigetdir('C:\Users\saul6\Documents\Electrooptical Eng\Thesis\Matlab\Video Recorder\video files\','Select a folder with the video files to be played');
                app.VideofilesfolderEditField.Value = dname;
            catch
                status(app, 'Error selecting this folder.', 'r', 1, 0);
            end
        end

        % Callback function
        function LoadlastrunButtonPushed(app, event)
           
           
        end

        % Value changed function: FPSCheckBox
        function FPSCheckBoxValueChanged(app, event)
            value = app.FPSCheckBox.Value;
            
            if value == 1
                app.UIAxes_FPS.Visible = 1;
            else
                plot(app.UIAxes_FPS, 0); % updates FPS graph
                app.UIAxes_FPS.Visible = 0;
            end
            
        end

        % Value changed function: IRcameraSwitch
        function IRcameraSwitchValueChanged(app, event)
            value = app.IRcameraSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp_5.Color = 'g';
                %app.IRcameraasButtonGroup.Visible
                app.TemperatureButton.Enable = 1;
                app.ColorButton.Enable = 1;

            else
                app.Lamp_5.Color = 'r';
                app.TemperatureButton.Enable = 0;
                app.ColorButton.Enable = 0;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            app.ExitButton.Text = 'Exit';
            
            app.RunButton.Enable = 1;
            app.StopButton.Enable = 0;
            
            app.FramerateDropDown.Enable = 1;
            app.FramerateDropDownLabel.Enable = 1;
            app.FPSCheckBox.Enable = 1;
            app.SaveSwitch.Enable = 1;
            app.PlayvideosSwitch.Enable = 1;
            app.VIScameraSwitch.Enable = 1;
            app.IRcameraSwitch.Enable = 1;
            app.LiveviewSwitch.Enable = 1;
            app.StopafterCheckBox.Enable = 1;
            app.WarmupCheckBox.Enable = 1;
            
            if strcmp(app.PlayvideosSwitch.Value, 'On')
                app.InitialblackscreenEditField.Enable = 1;
                app.InitialblackscreenEditFieldLabel.Enable = 1;
                app.PlaytimeDropDown.Enable = 1;
                app.PlaytimeDropDownLabel.Enable = 1;
                app.PausetimeEditField.Enable = 1;
                app.PausetimeEditFieldLabel.Enable = 1;
                app.LastblackscreenEditField.Enable = 1;
                app.LastblackscreenEditFieldLabel.Enable = 1;
                app.VideofilesfolderEditField.Enable = 1;
                app.VideofilesfolderEditFieldLabel.Enable = 1;
                app.AlwaysverifyVLCfullscreenCheckBox.Enable = 1;
                app.Button_dir.Enable = 1;
                app.QuestionsPopupCheckBox.Enable = 1;
                app.FromfolderListBox.Enable = 1;
                app.FromfolderListBoxLabel.Enable = 1;
                app.ToplayerListBox.Enable = 1;
                app.ToplayerListBoxLabel.Enable = 1;
                app.Button_right.Enable = 1;
                app.Button_left.Enable = 1;
                app.Button_refresh_list.Enable = 1;
                
                if app.QuestionsPopupCheckBox.Value == 1
                    app.PopuppositionEditField.Enable = 1;
                    app.PopuppositionEditFieldLabel.Enable = 1;
                    app.CheckpositionButton.Enable = 1;
                end
                
            end
            
            if strcmp(app.VIScameraSwitch.Value, 'On')
                app.RGB2grayCheckBox.Enable = 1;
                app.VIScameraDropDown.Enable = 1;
                app.VIScameraLabel.Enable = 1;
                app.VIScameraresolutionDropDown.Enable = 1;
                app.VIScameraresolutionLabel.Enable = 1;
                app.VIScameraRefresh.Enable = 1;
                app.CropCheckBox.Enable = 1;
                
                if app.CropCheckBox.Value == 1
                    app.Button_crop.Enable = 1;
                    app.CroppingcoordinatesEditField.Enable = 1;
                    app.CroppingcoordinatesEditFieldLabel.Enable = 1;
                end
                
            end
            
            if strcmp(app.SaveSwitch.Value, 'On')
                app.TitleEditField.Enable = 1;
                app.TitleEditFieldLabel.Enable = 1;
                app.SavetimestampCheckBox.Enable = 1;
            end
            
            if strcmp(app.IRcameraSwitch.Value, 'On')
                app.TemperatureButton.Enable = 1;
                app.ColorButton.Enable = 1;
            end
            
            if app.StopafterCheckBox.Value == 1
                app.EditField_stop.Enable = 1;
                app.SecondsButton_stop.Enable = 1;
                app.FramesButton_stop.Enable = 1;
                app.Label.Enable = 1;
            end
            
            if app.WarmupCheckBox.Value == 1
                app.EditField_start.Enable = 1;
                app.SecondsButton_start.Enable = 1;
                app.FramesButton_start.Enable = 1;
                app.Label_2.Enable = 1;
            end
            
            global viewer_is_running;
            viewer_is_running = 0;
            
        end

        % Value changed function: AlwaysverifyVLCfullscreenCheckBox
        function AlwaysverifyVLCfullscreenCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: QuestionsPopupCheckBox
        function QuestionsPopupCheckBoxValueChanged(app, event)
            value = app.QuestionsPopupCheckBox.Value;
            if value == 1
                app.PopuppositionEditField.Enable = 1;
                app.PopuppositionEditFieldLabel.Enable = 1;
                app.CheckpositionButton.Enable = 1;
            else
                app.PopuppositionEditField.Enable = 0;
                app.PopuppositionEditFieldLabel.Enable = 0;
                app.CheckpositionButton.Enable = 0;
            end
        end

        % Button pushed function: CheckpositionButton
        function CheckpositionButtonPushed(app, event)
            global glb;
            glb.pos = app.PopuppositionEditField.Value;
            popup;
        end

        % Button pushed function: VIScameraRefresh
        function VIScameraRefreshPushed(app, event)
            app.VIScameraDropDown.Items = webcamlist;
        end

        % Value changed function: VIScameraDropDown
        function VIScameraDropDownValueChanged(app, event)
            value = app.VIScameraDropDown.Value;
            try
                cam = webcam(value);
                app.VIScameraresolutionDropDown.Items = cam.AvailableResolutions;
                clear('cam');
            catch
                app.Status1.FontColor = [0.78,0.09,0.21]; % dark red
                app.Status1.Value = sprintf('%s', ['Error connecting to ' value]);
            end
            
        end

        % Button pushed function: Button_crop
        function Button_cropPushed(app, event)
            
            try
                cam = webcam(camera2connect);
                cam.Resolution = camera_resolution;
                frame = snapshot(cam); % get imgage from VIS camera
            catch
                status(app, 'Error connecting to VIS camera.', 'r', 1, 0);
            end
            
            crop_cor = ROIselect(app, frame);
            app.CroppingcoordinatesEditField.Value = num2str(crop_cor);
        end

        % Value changed function: CropCheckBox
        function CropCheckBoxValueChanged(app, event)
            value = app.CropCheckBox.Value;
            
            if value == 1
               app.Button_crop.Enable = 1;
               app.CroppingcoordinatesEditField.Enable = 1;
               app.CroppingcoordinatesEditFieldLabel.Enable = 1;
            else
               app.Button_crop.Enable = 0;
               app.CroppingcoordinatesEditField.Enable = 0;
               app.CroppingcoordinatesEditFieldLabel.Enable = 0;
            end
            
        end

        % Value changed function: VideofilesfolderEditField
        function VideofilesfolderEditFieldValueChanged(app, event)


        end

        % Button pushed function: Button_refresh_list
        function Button_refresh_listPushed(app, event)
            try
                app.Property = rmfield(app.Property,'list_name');
                app.Property = rmfield(app.Property,'list_idx');
                app.Property = rmfield(app.Property,'playlist_name');
                app.Property = rmfield(app.Property,'playlist_idx');
            catch
            end
            
            app.FromfolderListBox.Items = cellstr(' ');
            app.ToplayerListBox.Items = cellstr(' ');
            
            value = app.VideofilesfolderEditField.Value; % value is directory location
            app.Property.list = folder2list(app, value); % calling playlist function
            
            [r, c] = size(app.Property.list);
            
            if r == 1 && c == 1 && app.Property.list(1,1) == 0
                app.FromfolderListBox.Items = cellstr(' ');
                status(app, 'Error loading video files from folder.', 'r', 1, 0);
            else
                
                for i=1:r
                    app.Property.list_name(i) = cellstr(app.Property.list(i).name);
                    app.Property.list_idx(i) = app.Property.list(i).idx;
                end
                
                app.FromfolderListBox.Items = app.Property.list_name; % name vector
                len = length(app.FromfolderListBox.Items);
                app.FromfolderListBox.ItemsData = linspace(1,len,len); % idx vector
                
                status(app, 'Video files folder content loaded successfully.', 'g', 1, 0);
            end
            
            %catch
            %end
            
        end

        % Button pushed function: Button_right
        function Button_rightPushed(app, event)
            value = app.FromfolderListBox.Value;
            try
                
                if isfield(app.Property,'playlist_name') == 0
                    app.Property.playlist_name = cellstr(app.Property.list_name(value)); % create the vector for the first time
                    app.Property.playlist_idx = app.Property.list_idx(value);
                else
                    len = length(app.Property.playlist_name);
                    app.Property.playlist_name(len + 1) = cellstr(app.Property.list_name(value)); % add item to the vector
                    app.Property.playlist_idx(len + 1) = app.Property.list_idx(value);
                end
                
                app.ToplayerListBox.Items = app.Property.playlist_name;% update list
                len = length(app.ToplayerListBox.Items);
                app.ToplayerListBox.ItemsData = linspace(1,len,len);
                
            catch
            end
            
        end

        % Button pushed function: Button_left
        function Button_leftPushed(app, event)
            value = app.ToplayerListBox.Value;

            try
                
                if isempty(app.Property.playlist_name) == 0
                    len = length(app.Property.playlist_name);
                    
                    for i=0:len-value-1
                        app.Property.playlist_name(value + i) = app.Property.playlist_name(value + 1 + i); % move items up one by one
                        app.Property.playlist_idx(value + i) = app.Property.playlist_idx(value + 1 + i);
                    end
                    
                    app.Property.playlist_name(len) = []; % remove last item
                    app.Property.playlist_idx(len) = [];
                    
                end
                
                app.ToplayerListBox.Items = app.Property.playlist_name; % update list
                len = length(app.ToplayerListBox.Items);
                app.ToplayerListBox.ItemsData = linspace(1,len,len);
                
            catch
            end
            
        end

        % Value changed function: StopafterCheckBox
        function StopafterCheckBoxValueChanged(app, event)
            value = app.StopafterCheckBox.Value;
            
            if value == 1
                app.EditField_stop.Enable = 1;
                app.SecondsButton_stop.Enable = 1;
                app.FramesButton_stop.Enable = 1;
                app.Label.Enable = 1;
            else
                app.EditField_stop.Enable = 0;
                app.SecondsButton_stop.Enable = 0;
                app.FramesButton_stop.Enable = 0;
                app.Label.Enable = 0;
            end
            
        end

        % Value changed function: WarmupCheckBox
        function WarmupCheckBoxValueChanged(app, event)
            value = app.WarmupCheckBox.Value;
                        
            if value == 1
                app.EditField_start.Enable = 1;
                app.SecondsButton_start.Enable = 1;
                app.FramesButton_start.Enable = 1;
                app.Label_2.Enable = 1;
            else
                app.EditField_start.Enable = 0;
                app.SecondsButton_start.Enable = 0;
                app.FramesButton_start.Enable = 0;
                app.Label_2.Enable = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExperimentcontrolpanelUIFigure = uifigure;
            app.ExperimentcontrolpanelUIFigure.Name = 'Experiment control panel';
            app.TabGroup = uitabgroup(app.ExperimentcontrolpanelUIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.RunButton = uibutton(app.RecordTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Lamp = uilamp(app.RecordTab);
            app.Lamp_2 = uilamp(app.RecordTab);
            app.Lamp_3 = uilamp(app.RecordTab);
            app.Lamp_4 = uilamp(app.RecordTab);
            app.ExitButton = uibutton(app.RecordTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AlwaysverifyVLCfullscreenCheckBox = uicheckbox(app.RecordTab);
            app.AlwaysverifyVLCfullscreenCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlwaysverifyVLCfullscreenCheckBoxValueChanged, true);
            app.AlwaysverifyVLCfullscreenCheckBox.Enable = 'off';
            app.AlwaysverifyVLCfullscreenCheckBox.Text = 'Always verify VLC full screen';
            app.SavetimestampCheckBox = uicheckbox(app.RecordTab);
            app.SavetimestampCheckBox.Enable = 'off';
            app.SavetimestampCheckBox.Text = 'Save time stamp';
            app.Button_dir = uibutton(app.RecordTab, 'push');
            app.Button_dir.ButtonPushedFcn = createCallbackFcn(app, @Button_dirPushed, true);
            app.Button_dir.Icon = 'foldericon.png';
            app.Button_dir.Enable = 'off';
            app.Button_dir.Text = '';
            app.ExperimentcontrolpanelLabel = uilabel(app.RecordTab);
            app.ExperimentcontrolpanelLabel.Text = 'Experiment control panel';
            app.WrittenbyShaulShvimmerElectroOpticalengineeringMScstudentLabel = uilabel(app.RecordTab);
            app.WrittenbyShaulShvimmerElectroOpticalengineeringMScstudentLabel.Text = 'Written in MATLAB 2018b by Shaul Shvimmer, Electro-Optical engineering M.Sc student, saulsh@post.bgu.ac.il';
            app.Lamp_5 = uilamp(app.RecordTab);
            app.StopButton = uibutton(app.RecordTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.QuestionsPopupCheckBox = uicheckbox(app.RecordTab);
            app.QuestionsPopupCheckBox.ValueChangedFcn = createCallbackFcn(app, @QuestionsPopupCheckBoxValueChanged, true);
            app.QuestionsPopupCheckBox.Enable = 'off';
            app.QuestionsPopupCheckBox.Text = 'Questions Pop-up';
            app.CheckpositionButton = uibutton(app.RecordTab, 'push');
            app.CheckpositionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpositionButtonPushed, true);
            app.CheckpositionButton.Enable = 'off';
            app.CheckpositionButton.Text = 'Check position';
            app.VIScameraRefresh = uibutton(app.RecordTab, 'push');
            app.VIScameraRefresh.ButtonPushedFcn = createCallbackFcn(app, @VIScameraRefreshPushed, true);
            app.VIScameraRefresh.Icon = 'refreshicon.png';
            app.VIScameraRefresh.Text = '';
            app.Button_crop = uibutton(app.RecordTab, 'push');
            app.Button_crop.ButtonPushedFcn = createCallbackFcn(app, @Button_cropPushed, true);
            app.Button_crop.Icon = 'cropicon.png';
            app.Button_crop.Enable = 'off';
            app.Button_crop.Text = '';
            app.CropCheckBox = uicheckbox(app.RecordTab);
            app.CropCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropCheckBoxValueChanged, true);
            app.CropCheckBox.Text = 'Crop';
            app.RGB2grayCheckBox = uicheckbox(app.RecordTab);
            app.RGB2grayCheckBox.Text = 'RGB2gray';
            app.TimeLabel = uilabel(app.RecordTab);
            app.TimeLabel.Text = 'Time:';
            app.Status2 = uilabel(app.RecordTab);
            app.Status2.Text = '';
            app.SecLabel = uilabel(app.RecordTab);
            app.SecLabel.Text = '[Sec].';
            app.Button_right = uibutton(app.RecordTab, 'push');
            app.Button_right.ButtonPushedFcn = createCallbackFcn(app, @Button_rightPushed, true);
            app.Button_right.Icon = 'right-arrow-icon.png';
            app.Button_right.Enable = 'off';
            app.Button_right.Text = '';
            app.Button_left = uibutton(app.RecordTab, 'push');
            app.Button_left.ButtonPushedFcn = createCallbackFcn(app, @Button_leftPushed, true);
            app.Button_left.Icon = 'left-arrow-icon.png';
            app.Button_left.Enable = 'off';
            app.Button_left.Text = '';
            app.Button_refresh_list = uibutton(app.RecordTab, 'push');
            app.Button_refresh_list.ButtonPushedFcn = createCallbackFcn(app, @Button_refresh_listPushed, true);
            app.Button_refresh_list.Icon = 'refreshicon.png';
            app.Button_refresh_list.Enable = 'off';
            app.Button_refresh_list.Text = '';
            app.StopafterCheckBox = uicheckbox(app.RecordTab);
            app.StopafterCheckBox.ValueChangedFcn = createCallbackFcn(app, @StopafterCheckBoxValueChanged, true);
            app.StopafterCheckBox.Text = 'Stop after:';
            app.EditField_stop = uieditfield(app.RecordTab, 'numeric');
            app.EditField_stop.Enable = 'off';
            app.ButtonGroup = uibuttongroup(app.RecordTab);
            app.SecondsButton_stop = uiradiobutton(app.ButtonGroup);
            app.SecondsButton_stop.Enable = 'off';
            app.SecondsButton_stop.Text = 'Seconds';
            app.SecondsButton_stop.Value = true;
            app.FramesButton_stop = uiradiobutton(app.ButtonGroup);
            app.FramesButton_stop.Enable = 'off';
            app.FramesButton_stop.Text = 'Frames';
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Enable = 'off';
            app.Label.Text = '/';
            app.WarmupCheckBox = uicheckbox(app.RecordTab);
            app.WarmupCheckBox.ValueChangedFcn = createCallbackFcn(app, @WarmupCheckBoxValueChanged, true);
            app.WarmupCheckBox.Text = 'Warm up:';
            app.EditField_start = uieditfield(app.RecordTab, 'numeric');
            app.EditField_start.Enable = 'off';
            app.EditField_start.Value = 30;
            app.ButtonGroup_2 = uibuttongroup(app.RecordTab);
            app.SecondsButton_start = uiradiobutton(app.ButtonGroup_2);
            app.SecondsButton_start.Enable = 'off';
            app.SecondsButton_start.Text = 'Seconds';
            app.SecondsButton_start.Value = true;
            app.FramesButton_start = uiradiobutton(app.ButtonGroup_2);
            app.FramesButton_start.Enable = 'off';
            app.FramesButton_start.Text = 'Frames';
            app.Label_2 = uilabel(app.ButtonGroup_2);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '/';
            app.IRcameraasButtonGroup = uibuttongroup(app.RecordTab);
            app.IRcameraasButtonGroup.Title = 'IR camera as:';
            app.TemperatureButton = uiradiobutton(app.IRcameraasButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.TemperatureButton.Value = true;
            app.ColorButton = uiradiobutton(app.IRcameraasButtonGroup);
            app.ColorButton.Text = 'Color';
            app.SaveSwitchLabel = uilabel(app.RecordTab);
            app.SaveSwitchLabel.Text = 'Save';
            app.SaveSwitch = uiswitch(app.RecordTab, 'slider');
            app.SaveSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveSwitchValueChanged, true);
            app.VIScameraSwitchLabel = uilabel(app.RecordTab);
            app.VIScameraSwitchLabel.Text = 'VIS camera';
            app.VIScameraSwitch = uiswitch(app.RecordTab, 'slider');
            app.VIScameraSwitch.ValueChangedFcn = createCallbackFcn(app, @VIScameraSwitchValueChanged, true);
            app.VIScameraSwitch.Value = 'On';
            app.PlayvideosSwitchLabel = uilabel(app.RecordTab);
            app.PlayvideosSwitchLabel.Text = 'Play videos';
            app.PlayvideosSwitch = uiswitch(app.RecordTab, 'slider');
            app.PlayvideosSwitch.ValueChangedFcn = createCallbackFcn(app, @PlayvideosSwitchValueChanged, true);
            app.VideofilesfolderEditFieldLabel = uilabel(app.RecordTab);
            app.VideofilesfolderEditFieldLabel.Enable = 'off';
            app.VideofilesfolderEditFieldLabel.Text = 'Video files folder:';
            app.VideofilesfolderEditField = uieditfield(app.RecordTab, 'text');
            app.VideofilesfolderEditField.ValueChangedFcn = createCallbackFcn(app, @VideofilesfolderEditFieldValueChanged, true);
            app.VideofilesfolderEditField.Enable = 'off';
            app.VideofilesfolderEditField.Value = 'C:\Users\saul6\Documents\Electrooptical Eng\Thesis\Matlab\Video Recorder\video files\';
            app.TitleEditFieldLabel = uilabel(app.RecordTab);
            app.TitleEditFieldLabel.Enable = 'off';
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.RecordTab, 'text');
            app.TitleEditField.Enable = 'off';
            app.TitleEditField.Value = 'Experiment name goes here';
            app.FramerateDropDownLabel = uilabel(app.RecordTab);
            app.FramerateDropDownLabel.Text = 'Frame rate:';
            app.FramerateDropDown = uidropdown(app.RecordTab);
            app.FramerateDropDown.Items = {'Max', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.FramerateDropDown.Editable = 'on';
            app.FramerateDropDown.Value = '8';
            app.LiveviewSwitchLabel = uilabel(app.RecordTab);
            app.LiveviewSwitchLabel.Text = 'Live view';
            app.LiveviewSwitch = uiswitch(app.RecordTab, 'slider');
            app.LiveviewSwitch.ValueChangedFcn = createCallbackFcn(app, @LiveviewSwitchValueChanged, true);
            app.LiveviewSwitch.Value = 'On';
            app.InitialblackscreenEditFieldLabel = uilabel(app.RecordTab);
            app.InitialblackscreenEditFieldLabel.Enable = 'off';
            app.InitialblackscreenEditFieldLabel.Text = 'Initial black screen:';
            app.InitialblackscreenEditField = uieditfield(app.RecordTab, 'numeric');
            app.InitialblackscreenEditField.Enable = 'off';
            app.InitialblackscreenEditField.Value = 10;
            app.PausetimeEditFieldLabel = uilabel(app.RecordTab);
            app.PausetimeEditFieldLabel.Enable = 'off';
            app.PausetimeEditFieldLabel.Text = 'Pause time:';
            app.PausetimeEditField = uieditfield(app.RecordTab, 'numeric');
            app.PausetimeEditField.Enable = 'off';
            app.PausetimeEditField.Value = 10;
            app.LastblackscreenEditFieldLabel = uilabel(app.RecordTab);
            app.LastblackscreenEditFieldLabel.Enable = 'off';
            app.LastblackscreenEditFieldLabel.Text = 'Last black screen';
            app.LastblackscreenEditField = uieditfield(app.RecordTab, 'numeric');
            app.LastblackscreenEditField.Enable = 'off';
            app.LastblackscreenEditField.Value = 10;
            app.PlaytimeDropDownLabel = uilabel(app.RecordTab);
            app.PlaytimeDropDownLabel.Enable = 'off';
            app.PlaytimeDropDownLabel.Text = 'Play time';
            app.PlaytimeDropDown = uidropdown(app.RecordTab);
            app.PlaytimeDropDown.Items = {'Auto', '1', '3', '5', '10'};
            app.PlaytimeDropDown.Editable = 'on';
            app.PlaytimeDropDown.Enable = 'off';
            app.PlaytimeDropDown.Value = 'Auto';
            app.IRcameraSwitchLabel = uilabel(app.RecordTab);
            app.IRcameraSwitchLabel.Text = 'IR camera';
            app.IRcameraSwitch = uiswitch(app.RecordTab, 'slider');
            app.IRcameraSwitch.ValueChangedFcn = createCallbackFcn(app, @IRcameraSwitchValueChanged, true);
            app.IRcameraSwitch.Value = 'On';
            app.PopuppositionEditFieldLabel = uilabel(app.RecordTab);
            app.PopuppositionEditFieldLabel.Enable = 'off';
            app.PopuppositionEditFieldLabel.Text = 'Pop-up position:';
            app.PopuppositionEditField = uieditfield(app.RecordTab, 'text');
            app.PopuppositionEditField.Enable = 'off';
            app.PopuppositionEditField.Value = '2500,500,200,300';
            app.VIScameraDropDownLabel = uilabel(app.RecordTab);
            app.VIScameraDropDownLabel.Text = 'VIS camera:';
            app.VIScameraDropDown = uidropdown(app.RecordTab);
            app.VIScameraDropDown.Items = {};
            app.VIScameraDropDown.ValueChangedFcn = createCallbackFcn(app, @VIScameraDropDownValueChanged, true);
            app.VIScameraDropDown.Value = {};
            app.VIScameraresolutionDropDownLabel = uilabel(app.RecordTab);
            app.VIScameraresolutionDropDownLabel.Text = 'VIS camera resolution:';
            app.VIScameraresolutionDropDown = uidropdown(app.RecordTab);
            app.VIScameraresolutionDropDown.Items = {};
            app.VIScameraresolutionDropDown.Value = {};
            app.CroppingcoordinatesEditFieldLabel = uilabel(app.RecordTab);
            app.CroppingcoordinatesEditFieldLabel.Enable = 'off';
            app.CroppingcoordinatesEditFieldLabel.Text = 'Cropping coordinates';
            app.CroppingcoordinatesEditField = uieditfield(app.RecordTab, 'text');
            app.CroppingcoordinatesEditField.Enable = 'off';
            app.StatusLabel = uilabel(app.RecordTab);
            app.StatusLabel.Text = 'Status:';
            app.Status1 = uitextarea(app.RecordTab);
            app.FromfolderListBoxLabel = uilabel(app.RecordTab);
            app.FromfolderListBoxLabel.Enable = 'off';
            app.FromfolderListBoxLabel.Text = 'From folder:';
            app.FromfolderListBox = uilistbox(app.RecordTab);
            app.FromfolderListBox.Items = {};
            app.FromfolderListBox.Enable = 'off';
            app.FromfolderListBox.Value = {};
            app.ToplayerListBoxLabel = uilabel(app.RecordTab);
            app.ToplayerListBoxLabel.Enable = 'off';
            app.ToplayerListBoxLabel.Text = 'To player:';
            app.ToplayerListBox = uilistbox(app.RecordTab);
            app.ToplayerListBox.Items = {};
            app.ToplayerListBox.Enable = 'off';
            app.ToplayerListBox.Value = {};
            app.LiveStatisticsTab = uitab(app.TabGroup);
            app.LiveStatisticsTab.Title = 'Live Statistics';
            app.FPSCheckBox = uicheckbox(app.LiveStatisticsTab);
            app.FPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @FPSCheckBoxValueChanged, true);
            app.FPSCheckBox.Text = 'FPS';
            app.FPSCheckBox.Value = true;
            app.UIAxes_FPS = uiaxes(app.LiveStatisticsTab);
            xlabel(app.UIAxes_FPS, 'Time [Sec]')
            ylabel(app.UIAxes_FPS, 'FPS')
            app.ROI12CheckBox = uicheckbox(app.LiveStatisticsTab);
            app.ROI12CheckBox.Text = 'ROI 1 - 2';
            app.ROI12CheckBox.Value = true;
            app.UIAxes_ROIdiff = uiaxes(app.LiveStatisticsTab);
            xlabel(app.UIAxes_ROIdiff, 'Time [Sec]')
            ylabel(app.UIAxes_ROIdiff, 'Intensity')
            app.IntensityatROI1CheckBox = uicheckbox(app.LiveStatisticsTab);
            app.IntensityatROI1CheckBox.Text = 'Intensity at ROI 1';
            app.IntensityatROI1CheckBox.Value = true;
            app.UIAxes_ROI_1 = uiaxes(app.LiveStatisticsTab);
            xlabel(app.UIAxes_ROI_1, 'Time [Sec]')
            ylabel(app.UIAxes_ROI_1, 'Intensity')
            app.SelectROIButton_2 = uibutton(app.LiveStatisticsTab, 'state');
            app.SelectROIButton_2.Text = 'Select ROI';
            app.IntensityatROI2CheckBox = uicheckbox(app.LiveStatisticsTab);
            app.IntensityatROI2CheckBox.Text = 'Intensity at ROI 2';
            app.IntensityatROI2CheckBox.Value = true;
            app.UIAxes_ROI_2 = uiaxes(app.LiveStatisticsTab);
            xlabel(app.UIAxes_ROI_2, 'Time [Sec]')
            ylabel(app.UIAxes_ROI_2, 'Intensity')
            app.SelectROIButton_3 = uibutton(app.LiveStatisticsTab, 'state');
            app.SelectROIButton_3.Text = 'Select ROI';
            app.AnalayzeTab = uitab(app.TabGroup);
            app.AnalayzeTab.Title = 'Analayze';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main_V2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExperimentcontrolpanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExperimentcontrolpanelUIFigure)
        end
    end
end